classdef binarize_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        computeButton  matlab.ui.control.Button
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        loadButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filename = uigetfile('*.png');
    % read image
    im=imread(filename);
    % % plot image
    fig = app.UIFigure;
    ax = uiaxes(fig,'Position',[207,272,232,100]);
    imshow(im,'Parent',ax);
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            filename = uigetfile('*.png');
        % read image
        im1=imread(filename);
        % compute image
        imgray = rgb2gray(im1);
        BW = imbinarize(imgray);
        % plot images
        %imshow(BW*255);
        fig = app.UIFigure;
        ax = uiaxes(fig,'Position',[206,80,232,100]);
        imshow(BW*255,'Parent',ax);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '二値化';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '画像表示';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binarize_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end